classdef RevolutBudgetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LoadMenu                 matlab.ui.container.Menu
        GroupsSwitch             matlab.ui.control.Switch
        GroupsSwitchLabel        matlab.ui.control.Label
        SankeyTextButton         matlab.ui.control.Button
        TypeList                 matlab.ui.control.ListBox
        TypeListBoxLabel         matlab.ui.control.Label
        PlotType                 matlab.ui.control.DropDown
        PlotTypeDropDownLabel    matlab.ui.control.Label
        ClearSelection           matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
        Received                 matlab.ui.control.NumericEditField
        ReceivedEditFieldLabel   matlab.ui.control.Label
        Spent                    matlab.ui.control.NumericEditField
        SpentEditFieldLabel      matlab.ui.control.Label
        UniqueDescriptionsList   matlab.ui.control.ListBox
        UniqueDescriptionsLabel  matlab.ui.control.Label
        AccountsList             matlab.ui.control.ListBox
        AccountsListBoxLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        rawData % data as imported from file
        Type % Type column of data
        Product % Product column of data
        StartDate % Started Date column of data
        CompleteDate % Completed Date column of data
        Description % Description column of data
        Amount % Amount column of data
        Fee % Fee column of data
        Currency % Currency column of data
        State % State column of data
        Balance % Balance column of data
        
        UniqueDescriptions % Unique elements from app.Description
        UniqueProduct % Unqiue products from app.Product
        UniqueCurrency % Unique currencies from app.Currency
        
        CurrentProductIdxs % Indices of the amounts currently selected
    end
    
    methods (Access = private)
        
        function updateSpending(app)
            transactions = app.Amount(applyFiltersToData(app));
            
            spent = abs(sum(transactions(transactions < 0)));
            received = sum(transactions(transactions > 0));
            
            app.Received.Value = received;
            app.Spent.Value = spent;
            app.Received.Enable = 'on';
            app.Spent.Enable = 'on';
        end
        
        function logicalIdxs = applyFiltersToData(app)
            %First the account, then the type and then the description
            account = app.AccountsList.Value;
            types = app.TypeList.Value;
            if isempty(types)
                types = app.Type(app.Product == account);
            end
            descriptions = app.UniqueDescriptionsList.Value;
            if isempty(descriptions)
                descriptions = app.UniqueDescriptions;
            end
            
            accountsIdxs = app.Product == account;
            typesIdxs = ismember(app.Type,types);
            descriptionsIdxs = ismember(app.Description,descriptions);
            
            combinedIdxs = accountsIdxs.*typesIdxs.*descriptionsIdxs;
            logicalIdxs = logical(combinedIdxs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            %Load the data from the file
            [file,path] = uigetfile('account-statement*.csv');
            if length(file) ~= 1 && length(path) ~= 1
                app.rawData = readcell(strcat(path,file));
                
                %Save the data in their respective variables
                app.Type = string(app.rawData(2:end,1));
                app.Product = string(app.rawData(2:end,2));
                app.StartDate = app.rawData(2:end,3);
                app.CompleteDate = app.rawData(2:end,4);
                app.Description = string(app.rawData(2:end,5));
                app.Amount = cell2mat(app.rawData(2:end,6));
                app.Fee = cell2mat(app.rawData(2:end,7));
                app.Currency = string(app.rawData(2:end,8));
                app.State = string(app.rawData(2:end,9));
                app.Balance = cell2mat(app.rawData(2:end,10));
                
                app.UniqueDescriptions = unique(app.Description);
                app.UniqueProduct = unique(app.Product);
                app.UniqueCurrency = unique(app.Currency);
                
                %Update UI elements with the new data
                app.AccountsList.Items = app.UniqueProduct;
                %app.UniqueDescriptionsList.Items = app.UniqueDescriptions;
                app.AccountsList.Enable = 'on';
            end
        end

        % Value changed function: AccountsList
        function AccountsListValueChanged(app, event)
            value = app.AccountsList.Value;
            
            %Set the descriptions to the unique ones of this account
            descriptions = app.Description(app.Product == value);
            app.UniqueDescriptionsList.Items = unique(descriptions);
            types = app.Type(app.Product == value);
            app.TypeList.Items = unique(types);
            
            app.UniqueDescriptionsList.Enable = 'on';
            app.ClearSelection.Enable = 'on';
            app.PlotButton.Enable = 'on';
            app.PlotType.Enable = 'on';
            app.TypeList.Enable = 'on';
            app.SankeyTextButton.Enable = 'on';
            app.GroupsSwitch.Enable = 'on';
            
            app.CurrentProductIdxs = app.Product == value;
            
            %Update the spent and received money
            updateSpending(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            value = app.PlotType.Value;
            
            switch value
                case "Account Balance"
                    %get dates to datetime format
                    datetime1 = datetime(string(app.CompleteDate),"InputFormat","dd-MMM-yyyy HH:mm:ss");
                    try
                        datetime2 = datetime(string(app.CompleteDate),"InputFormat","dd-MMM-yyyy");
                        DateNaT = isnat(datetime1);
                        datetime1(DateNaT) = datetime2(DateNaT);
                    catch
                        warning("Date format is in dd-MMM-yyyy HH:mm:ss, so no need to extract as dd-MMM-yyyy.");
                    end
                    figure;
                    plot(datetime1(app.CurrentProductIdxs),app.Balance(app.CurrentProductIdxs));
                    ylabel("Amount in account");
                case "Spent by Description"
                    descriptions = unique(app.Description(applyFiltersToData(app)));
                    
                    amounts = zeros([2,length(descriptions)]);
                    for i = 1:length(descriptions)
                        currentAmounts = app.Amount(app.CurrentProductIdxs);
                        currentDescriptions = app.Description(app.CurrentProductIdxs);
                        amount = currentAmounts(currentDescriptions == descriptions{i});
                        
                        amounts(1,i) = abs(sum(amount(amount < 0)));
                        amounts(2,i) = sum(amount(amount > 0));
                    end
                    
                    figure;
                    X = categorical(descriptions);
                    X = reordercats(X,descriptions);
                    bar(X,amounts);
                    ylabel("Amount spent");
                    legend("Spent","Received");
                case "Transactions by Description"
                    descriptions = unique(app.Description(applyFiltersToData(app)));
                    
                    amounts = zeros([1,length(descriptions)]);
                    for i = 1:length(descriptions)
                        currentAmounts = app.Amount(app.CurrentProductIdxs);
                        currentDescriptions = app.Description(app.CurrentProductIdxs);
                        amount = currentAmounts(currentDescriptions == descriptions{i});
                        
                        amounts(1,i) = length(amount);
                    end
                    
                    figure;
                    X = categorical(descriptions);
                    X = reordercats(X,descriptions);
                    bar(X,amounts);
                    ylabel("Nº Transactions");
                case "Average Spent per Transaction"
                    descriptions = unique(app.Description(applyFiltersToData(app)));
                    
                    transactions = zeros([1,length(descriptions)]);
                    amounts = zeros([1,length(descriptions)]);
                    for i = 1:length(descriptions)
                        currentAmounts = app.Amount(app.CurrentProductIdxs);
                        currentDescriptions = app.Description(app.CurrentProductIdxs);
                        amount = currentAmounts(currentDescriptions == descriptions{i});
                        
                        transactions(1,i) = length(amount) - length(amount(amount > 0));
                        amounts(1,i) = abs(sum(amount(amount < 0)));
                    end
                    
                    figure;
                    X = categorical(descriptions);
                    X = reordercats(X,descriptions);
                    bar(X,amounts./transactions);
                    ylabel("Spent/Nº Transactions");
            end
        end

        % Value changed function: UniqueDescriptionsList
        function UniqueDescriptionsListValueChanged(app, event)
            value = app.UniqueDescriptionsList.Value;
            
            %Update the spent and received money
            updateSpending(app);
        end

        % Button pushed function: ClearSelection
        function ClearSelectionButtonPushed(app, event)
            app.UniqueDescriptionsList.Value = {};
            updateSpending(app);
        end

        % Value changed function: TypeList
        function TypeListValueChanged(app, event)
            value = app.TypeList.Value;
            
            %Update the values of UniqueDescriptionsList
            account = app.AccountsList.Value;
            types = app.TypeList.Value;
            if isempty(types)
                types = app.Type(app.Product == account);
            end
            
            accountsIdxs = app.Product == account;
            typesIdxs = ismember(app.Type,types);
            
            combinedIdxs = accountsIdxs.*typesIdxs;
            descriptions = app.Description(logical(combinedIdxs));
            
            app.UniqueDescriptionsList.Items = unique(descriptions);
            updateSpending(app);
        end

        % Button pushed function: SankeyTextButton
        function SankeyTextButtonPushed(app, event)
            %Create file containing the text to add to https://sankeymatic.com/build/
            currentAccount = string(app.AccountsList.Value);
            selection = app.UniqueDescriptionsList.Value;
            
            if isempty(selection)
                selection = app.UniqueDescriptionsList.Items;
            end
            
            textLines = cell([length(selection),1]);
            for i = 1:length(selection)
                idxs = app.CurrentProductIdxs.*(app.Description == selection(i));
                values = app.Amount(logical(idxs));
                
                if isempty(values(values < 0)) && ~isempty(values(values > 0))
                    value = sum(values(values > 0));
                    strTxt = strcat(selection(i)," [",string(value),"] ",currentAccount);
                    textLines{i} = strTxt;
                elseif ~isempty(values(values < 0))
                    value = abs(sum(values(values < 0)));
                    strTxt = strcat(currentAccount," [",string(value),"] ",selection(i));
                    textLines{i} = strTxt;
                end
            end
            
            tableTxt = table(textLines);
            writetable(tableTxt,"SankeyTextDescriptions",'WriteVariableNames',false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.AccountsListBoxLabel = uilabel(app.UIFigure);
            app.AccountsListBoxLabel.Enable = 'off';
            app.AccountsListBoxLabel.Text = 'Accounts';
            app.AccountsList = uilistbox(app.UIFigure);
            app.AccountsList.Items = {};
            app.AccountsList.ValueChangedFcn = createCallbackFcn(app, @AccountsListValueChanged, true);
            app.AccountsList.Enable = 'off';
            app.AccountsList.Value = {};
            app.UniqueDescriptionsLabel = uilabel(app.UIFigure);
            app.UniqueDescriptionsLabel.Enable = 'off';
            app.UniqueDescriptionsLabel.Text = {'Unique'; 'Descriptions'};
            app.UniqueDescriptionsList = uilistbox(app.UIFigure);
            app.UniqueDescriptionsList.Items = {};
            app.UniqueDescriptionsList.Multiselect = 'on';
            app.UniqueDescriptionsList.ValueChangedFcn = createCallbackFcn(app, @UniqueDescriptionsListValueChanged, true);
            app.UniqueDescriptionsList.Enable = 'off';
            app.UniqueDescriptionsList.Value = {};
            app.SpentEditFieldLabel = uilabel(app.UIFigure);
            app.SpentEditFieldLabel.Enable = 'off';
            app.SpentEditFieldLabel.Text = 'Spent';
            app.Spent = uieditfield(app.UIFigure, 'numeric');
            app.Spent.Editable = 'off';
            app.Spent.Enable = 'off';
            app.ReceivedEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedEditFieldLabel.Enable = 'off';
            app.ReceivedEditFieldLabel.Text = 'Received';
            app.Received = uieditfield(app.UIFigure, 'numeric');
            app.Received.Editable = 'off';
            app.Received.Enable = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ClearSelection = uibutton(app.UIFigure, 'push');
            app.ClearSelection.ButtonPushedFcn = createCallbackFcn(app, @ClearSelectionButtonPushed, true);
            app.ClearSelection.Enable = 'off';
            app.ClearSelection.Text = 'Clear Selection';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Enable = 'off';
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotType = uidropdown(app.UIFigure);
            app.PlotType.Items = {'Account Balance', 'Spent by Description', 'Transactions by Description', 'Average Spent per Transaction'};
            app.PlotType.Enable = 'off';
            app.PlotType.Value = 'Account Balance';
            app.TypeListBoxLabel = uilabel(app.UIFigure);
            app.TypeListBoxLabel.Enable = 'off';
            app.TypeListBoxLabel.Text = 'Type';
            app.TypeList = uilistbox(app.UIFigure);
            app.TypeList.Items = {};
            app.TypeList.Multiselect = 'on';
            app.TypeList.ValueChangedFcn = createCallbackFcn(app, @TypeListValueChanged, true);
            app.TypeList.Enable = 'off';
            app.TypeList.Value = {};
            app.SankeyTextButton = uibutton(app.UIFigure, 'push');
            app.SankeyTextButton.ButtonPushedFcn = createCallbackFcn(app, @SankeyTextButtonPushed, true);
            app.SankeyTextButton.Enable = 'off';
            app.SankeyTextButton.Text = 'Sankey Text';
            app.GroupsSwitchLabel = uilabel(app.UIFigure);
            app.GroupsSwitchLabel.Enable = 'off';
            app.GroupsSwitchLabel.Text = 'Groups';
            app.GroupsSwitch = uiswitch(app.UIFigure, 'slider');
            app.GroupsSwitch.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RevolutBudgetting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end